classdef VisionProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HistogramTab                   matlab.ui.container.Tab
        OriginalImage                  matlab.ui.control.UIAxes
        BrowseButton                   matlab.ui.control.Button
        HistorEqualizationDropdown     matlab.ui.control.DropDown
        ApplyButton                    matlab.ui.control.Button
        GrayScale1                     matlab.ui.control.UIAxes
        EqualizedImage                 matlab.ui.control.UIAxes
        SelectwhichdoyouwanttoapplytoyourImageLabel  matlab.ui.control.Label
        Data                           matlab.ui.control.Label
        UploadYourImagetoStartLabel    matlab.ui.control.Label
        HistogramGraph                 matlab.ui.control.UIAxes
        EqHistogramGraph               matlab.ui.control.UIAxes
        FilterTab                      matlab.ui.container.Tab
        GrayScale2                     matlab.ui.control.UIAxes
        ChooseFilterPanel              matlab.ui.container.Panel
        SobelorLaplaceDropDown         matlab.ui.control.DropDown
        ApplyFilterButton              matlab.ui.control.Button
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.EditField
        HorizontalorVertical           matlab.ui.control.DropDown
        FilterGraph                    matlab.ui.control.UIAxes
        FourierTab                     matlab.ui.container.Tab
        GrayScale3                     matlab.ui.control.UIAxes
        FourierGraph                   matlab.ui.control.UIAxes
        ApplyFourierButton             matlab.ui.control.Button
        NoiseTab                       matlab.ui.container.Tab
        AddNoisePanel                  matlab.ui.container.Panel
        ApplyNoiseButton               matlab.ui.control.Button
        AddNoiseDropDown               matlab.ui.control.DropDown
        PercentageofNoisyPixelsLabel   matlab.ui.control.Label
        PercentageofNoisyPixelsEditField  matlab.ui.control.EditField
        SinorCosDropDownLabel          matlab.ui.control.Label
        SinorCosDropDown               matlab.ui.control.DropDown
        nxEditFieldLabel               matlab.ui.control.Label
        nxEditField                    matlab.ui.control.EditField
        nyEditFieldLabel               matlab.ui.control.Label
        nyEditField                    matlab.ui.control.EditField
        RemoveNoisePanel               matlab.ui.container.Panel
        RemoveNoiseButton              matlab.ui.control.Button
        MedianFilerSizeEditFieldLabel  matlab.ui.control.Label
        MedianFilerSizeEditField       matlab.ui.control.EditField
        RemoveByDropDownLabel          matlab.ui.control.Label
        RemoveByDropDown               matlab.ui.control.DropDown
        NoisyImageGraph                matlab.ui.control.UIAxes
        RemoveNoiseGraph               matlab.ui.control.UIAxes
        GrayScale4                     matlab.ui.control.UIAxes
        FourierofNoise                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image;
        grayim
        spnoise
        periodicnoise
        fourierperiodicnoise
        rowsOfMaxes
        colsOfMaxes
        Pixel1
        Pixel2
        scheck
        pcheck
    end
    
    methods (Access = private)
        
         % A Function I created to Set the visibility of all object and the
         % title also it is responsible for seting the text of labels in
         % the GUI its parameters are the object ( can be in for of array ) , wheather its visibility
         % is on or off and a string to which according to the type of the
         % object it determines if it is a title or label text 
        function VisibiltyofGraph(app,Object,ON_OFF,string)
            
            for z=1:size(Object,2) % A for loop on the size of objects the function may take an array of objects
                if ON_OFF(z) ==""  % Checks made to set the visibility of the object 
                else
                    Object(z).Visible= ON_OFF(z);
                end
                if size(Object,2)==size(string,2) % works only if the string array size equals to the object array size otherwise it doesnot set string 
                    if string(z)==""
                    else
                        switch Object(z).Type  % a switch on the type of obj to set the string as a title or label 
                            case "axes"
                                Object(z).Title.String=string(z);
                                Object(z).Toolbar.Visible = 'off';
                            case "uilabel"
                                Object(z).Text=string(z);
                                
                        end
                    end
                end
            end
        end
        
        % A function I created to calculate the positions of the Peaks in
        % the fourier of periodic noise it takes a parameter that is the
        % fourier periodic noise
        function PeaksGetter(app,obj)
            fa = abs(obj);
            fm = max(fa(:));
            absFFT_Image = fa/fm;
            %After getting the absFFT i made the matrix into a new temp matrix
            %then i find the max which is the center pixel 
            %and i then set this pixel with 0 now i repeat the max method i
            %then i get the max pixels ( Peaks needed)
            temp=absFFT_Image;
            maxValue = max(temp(:));
            % Removing the Central Pixel to be able to see the peaks (they are the max now)
            [app.rowsOfMaxes, app.colsOfMaxes] = find(temp == maxValue);
            temp(app.rowsOfMaxes,app.colsOfMaxes)=0;
            maxValue = max(temp(:));
            [app.rowsOfMaxes, app.colsOfMaxes] = find(temp == maxValue);
            
        end
        
        
        %A get input from user it takes the graph as a parameter and then
        %get the position of outputs
        function GetInputFromUser(app,obj)
            %Setting the limits of the axes to focus on the peaks pixles
            obj.YLim=[app.rowsOfMaxes(1)-2  app.rowsOfMaxes(2)+2] ;
            obj.XLim=[app.colsOfMaxes(1)-2  app.colsOfMaxes(2)+2] ;
            count=0;
            
            % A for loop to 2 that allows the user to get select 2 points
            % and then if checks if the selected is equals to peaks if so
            % it contiues if no it gives a warning that the noise wont be
            % removed effectively and then continues with the choices
            for x = 1:2
                roi(x) = drawpoint(obj);
                temp=round(get(roi(x),'Position'));
                if ((temp(2)==app.rowsOfMaxes(1) && temp(1)==app.colsOfMaxes(1)) || (temp(2)==app.rowsOfMaxes(2) && temp(1)==app.colsOfMaxes(2)))
                    
                    
                else
                    if(count==0)
                        uiwait(warndlg(" Noise will not be removed effectively due to chosen Pixel "));
                        count=1;
                    end
                end
                drawnow;
            end
            %Setting the pixels chosen by the user to process
            app.Pixel1=round(get(roi(1),'Position'));
            app.Pixel2=round(get(roi(2),'Position'));
            delete(roi);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %uploading the image and setting the data of the original and computing the grayscale
            [filename,pathname,index]=uigetfile({'*.jpg';'*.jpeg';'*.png';'*.bmp';'*.oct';'*.tif'},"Open file");
            
            figure(app.UIFigure);
            fullpahtname=[pathname,filename];
            %checking on the file name if found if not it gives a warning 
            if fullpahtname 
                [fPath, fName, fExt] = fileparts(filename);
                %checking on the ext to decide if the user entered a wrong
                %file format and then give warning
                if(lower(fExt)==".jpg"||lower(fExt)==".jpeg"||lower(fExt)==".png"||lower(fExt)==".bmp"||lower(fExt)==".oct"||lower(fExt)==".tif")
                    app.image= imread(fullpahtname);
                    app.UploadYourImagetoStartLabel.Text="Image Selcted";
                    app.grayim = rgb2gray(app.image);
                    
                    % Number of Pixels (Extra Information for the user in the GUI)
                    data= "Number of Pixels in Original Image:" +newline+ string(numel(app.image )) + newline+"Number of Pixels in GrayScale Image:" +newline + string(numel(app.grayim ));
                    % Creating an array of the axes and their visibility and Titles and passing them
                    %to the to VisibiltyofGraph avoid repition of code
                    Object=[app.OriginalImage,app.GrayScale1,app.GrayScale2,app.GrayScale3,app.GrayScale4,app.HistorEqualizationDropdown,app.ApplyButton,app.Data,app.SelectwhichdoyouwanttoapplytoyourImageLabel];
                    on_off=["","on","on","on","on","on","on","on","on"];
                    text=["Original Image","GrayScale Image","GrayScale Image","GrayScale Image","GrayScale Image","","",data,""];
                    VisibiltyofGraph(app,Object,on_off,text);
                    % Drawing the Images
                    imshow(app.image,'parent',app.OriginalImage);
                    imshow(app.grayim,'parent',app.GrayScale1);
                    imshow(app.grayim,'parent',app.GrayScale2);
                    imshow(app.grayim,'parent',app.GrayScale3);
                    imshow(app.grayim,'parent',app.GrayScale4);
                else
                    uiwait(errordlg("Plz Choose an accepted type"));
                end
            else
                   uiwait(warndlg("No File Selected"));
            end
            %To prevent the GUI from going to the back of the desktop 
            drawnow;
            figure(app.UIFigure);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Checks if the Dropdown Value is Histogram or Eqalization and Plots them
            if app.HistorEqualizationDropdown.Value == "Histogram"
                [counts,binLocations] = imhist(app.grayim);
                %Setting the visibilty and then drawing
                VisibiltyofGraph(app,app.HistogramGraph,"on","Histogram of Image");
                bar(app.HistogramGraph,binLocations,counts,'FaceColor','r');
            elseif  app.HistorEqualizationDropdown.Value == "Histogram Equalization"
                %apply the histogram Equalization to the grayscale image
                eq_image = histeq(app.grayim);
                [counts,binLocations] = imhist(eq_image);
                %Setting the visibilty and then drawing
                VisibiltyofGraph(app,app.EqualizedImage,"on","Equalized Image");
                VisibiltyofGraph(app,app.EqHistogramGraph,"on","Histogram of Equalized Image");
                bar(app.EqHistogramGraph,binLocations,counts,'FaceColor','r');
                imshow(eq_image,'parent',app.EqualizedImage);
            end
            drawnow;
        end

        % Value changed function: SobelorLaplaceDropDown
        function SobelorLaplaceDropDownValueChanged(app, event)
            % a function that changes the Label and Value according to the mode wheather Sobel or Laplace
            value = app.SobelorLaplaceDropDown.Value;
            if value == "Sobel"
                app.ThresholdEditFieldLabel.Text="Threshold";
                app.ThresholdEditField.Value="Default";
                app.HorizontalorVertical.Visible="on";
            else
                app.ThresholdEditFieldLabel.Text="Alpha";
                app.ThresholdEditField.Value="Default";
                app.HorizontalorVertical.Visible="off";
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            %this function process the filters if Sobel or Laplace 
            value = app.SobelorLaplaceDropDown.Value;
            if value == "Sobel" %Sobel Filter Chosen 
                %Checks if the user entered a Threshold or no if no then it
                %sets it as a the default value of the egde sobel function
                % It also sets the Direction from the dropdown in the GUI
                if app.ThresholdEditField.Value=="Default"
                    edgeimg=edge(app.grayim, "Sobel",app.HorizontalorVertical.Value);
                    VisibiltyofGraph(app,app.FilterGraph,"on","Sobel Filter Applied"+ " in"+ " " + app.HorizontalorVertical.Value+" direction");
                    imshow(edgeimg,'parent',app.FilterGraph);
                else
                    %Setting the threshold value entered and making sure it
                    %is in range of 0-1 and if not it gives an error
                    %message
                    if( str2double(app.ThresholdEditField.Value) >=0 && str2double(app.ThresholdEditField.Value) <=1)
                        edgeimg=edge(app.grayim, "Sobel",str2double(app.ThresholdEditField.Value),app.HorizontalorVertical.Value);
                        VisibiltyofGraph(app,app.FilterGraph,"on","Sobel Filter Applied"+ " in"+ " " + app.HorizontalorVertical.Value+" direction");
                        imshow(edgeimg,'parent',app.FilterGraph);
                    else
                        uiwait(warndlg("Plz Choose a Threshold in range 0-1"));
                    end
                end
            else %Laplace Filter option doing the same exact things but without the direction 
                if app.ThresholdEditField.Value=="Default"
                    lap=fspecial("laplacian");
                    lapI=filter2(lap,app.grayim);
                    VisibiltyofGraph(app,app.FilterGraph,"on","Laplace Filter Applied");
                    imshow(lapI,'parent',app.FilterGraph);
                else
                    if( str2double(app.ThresholdEditField.Value) >=0 && str2double(app.ThresholdEditField.Value) <=1)
                        lap=fspecial("laplacian",str2double(app.ThresholdEditField.Value));
                        lapI=filter2(lap,app.grayim);
                        VisibiltyofGraph(app,app.FilterGraph,"on","Laplace Filter Applied");
                        imshow(lapI,'parent',app.FilterGraph);
                    else
                        uiwait(warndlg("Plz Choose a Alpha in range 0-1"));
                    end
                    
                end
                
            end
        end

        % Button pushed function: ApplyFourierButton
        function ApplyFourierButtonPushed(app, event)
            % displaying the fourier of the grayscale im 
            %Code from tutorial nothing new 
            fourier = fftshift(fft2(app.grayim));
            fl = log(1+abs(fourier));
            fm = max(fl(:));
            app.FourierGraph.Title.String = "Fourier of Image";
            imshow(im2uint8(fl/fm),'parent',app.FourierGraph);
        end

        % Button pushed function: ApplyNoiseButton
        function ApplyNoiseButtonPushed(app, event)
            app.scheck=0; % to check if user applied sobel or no and prevent removing before adding
            app.pcheck=0; % to check if user applied periodic or no and prevent removing before adding
            % Applying the Noise by checking the user selction first if it
            cla(app.FourierofNoise,'reset')
            VisibiltyofGraph(app,app.FourierofNoise,"off","");
            if app.AddNoiseDropDown.Value=="Salt and Pepper "
                %Checking if the Percantage is the default which is 5%  and
                %then applies the noise
                if app.PercentageofNoisyPixelsEditField.Value == "Default"
                    cla(app.NoisyImageGraph);
                    app.spnoise = imnoise(app.grayim,"salt & pepper"); % default noise density d = 0.05, 5%
                    VisibiltyofGraph(app,app.NoisyImageGraph,"on","Salt and Pepper noise Added original ");
                    imshow(app.spnoise,'parent',app.NoisyImageGraph);
                    app.scheck=1;
                else
                    %if not the default it takes the user value and then
                    %divides by 100 to get the percantage  and then applies
                    %the noise
                    if( str2double(app.PercentageofNoisyPixelsEditField.Value) >=0 && str2double(app.PercentageofNoisyPixelsEditField.Value) <=100)
                        cla(app.NoisyImageGraph);
                        app.spnoise = imnoise(app.grayim,"salt & pepper",str2double(app.PercentageofNoisyPixelsEditField.Value)/100); % default noise density d = 0.05, 5%
                        VisibiltyofGraph(app,app.NoisyImageGraph,"on","Salt and Pepper noise Added with percentage=" + string(app.PercentageofNoisyPixelsEditField.Value));
                        imshow(app.spnoise,'parent',app.NoisyImageGraph);
                        app.scheck=1;
                    else
                        uiwait(warndlg("Plz Choose a value in range 0-100"));
                    end
                end
                

            else
                % if the chosen if periodic it then makes a meshgrid and
                % then calculate the Wx and Wy values 
                %code mainly from tutorial
                s = size(app.grayim);
                [x, y]= meshgrid(1:s(2),1:s(1));
                Wx = max(max(x));
                Wy = max(max(y));
                % a check that that if no n of cycles in x or y is given it
                % sets them to zero 
                if(app.nxEditField.Value=="")
                    app.nxEditField.Value="0";
                end
                if(app.nyEditField.Value=="")
                    app.nyEditField.Value="0";
                end
                if(app.nxEditField.Value=="0" && app.nyEditField.Value=="0" )
                    uiwait(warndlg("Image would be the same as values for nx=0 and ny=0"));
                    
                end
                %setting nx and ny
                nx=str2double(app.nxEditField.Value);
                ny=str2double(app.nyEditField.Value);
                fx = nx/Wx;
                fy = ny/Wy;
                % checking if sin or cos is chosen and applies it 
                if app.SinorCosDropDown.Value=="Sin"
                    pxy = sin(2*pi*fx*x + 2*pi*fy*y)+1;
                else
                    pxy = cos(2*pi*fx*x + 2*pi*fy*y)+1;
                end
                % Showing the Noisy Image 
                app.periodicnoise=mat2gray((im2double(app.grayim)+pxy));
                cla(app.NoisyImageGraph);
                VisibiltyofGraph(app,app.NoisyImageGraph,"on","Periodic Noise Added");
                imshow(app.periodicnoise,'parent',app.NoisyImageGraph);
                app.pcheck=1;
            end
            app.RemoveNoisePanel.Visible="on";
        end

        % Value changed function: AddNoiseDropDown
        function AddNoiseDropDownValueChanged(app, event)
           % A function Callback that mainly is used to switch between the
           % Salt and pepper and Periodic options by settings object's
           % visibility 
            value = app.AddNoiseDropDown.Value;
            if value=="Salt and Pepper "
                onoff=["on","on","off","off","off","off","off","off","off","off","on","on"];
                obj=[app.PercentageofNoisyPixelsLabel,app.PercentageofNoisyPixelsEditField,...
                app.nxEditField, app.nyEditField,app.nxEditFieldLabel,app.nyEditFieldLabel,...
                app.SinorCosDropDownLabel,app.SinorCosDropDown,app.RemoveByDropDown,...
                app.RemoveByDropDownLabel, app.MedianFilerSizeEditField,app.MedianFilerSizeEditFieldLabel];
                VisibiltyofGraph(app,obj,onoff,"False");
                app.PercentageofNoisyPixelsEditField.Value = "Default";
            else
                onoff=["on","on","on","on","on","on","on","on","off","off","off","off"];
                obj=[app.RemoveByDropDown,app.RemoveByDropDownLabel,...
                app.nxEditField, app.nyEditField,app.nxEditFieldLabel,app.nyEditFieldLabel,...
                app.SinorCosDropDownLabel,app.SinorCosDropDown,app.PercentageofNoisyPixelsLabel,...
                app.PercentageofNoisyPixelsEditField, app.MedianFilerSizeEditField,app.MedianFilerSizeEditFieldLabel];
                VisibiltyofGraph(app,obj,onoff,"");
            end
            
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
            %A call back function that removes the noise
            value = app.AddNoiseDropDown.Value;
            cla(app.FourierofNoise,'reset')
            VisibiltyofGraph(app,app.FourierofNoise,"off","");
            % if the chosen is salt and pepper a median filter is applied
            % to the noisy image it first checks if the user enter a size
            % to the matrix (N) which translates to NxN in the code if not
            % it aoolies the default median filter otherwise apply the custom median filter
            if value=="Salt and Pepper "
                if(app.scheck==1)
                    bol=0;
                    if app.MedianFilerSizeEditField.Value == "Default"
                        medianfil = medfilt2(app.spnoise);
                        bol=1;
                    else
                        if(str2double(app.MedianFilerSizeEditField.Value)>=0)
                            medianfil = medfilt2(app.spnoise,[str2double(app.MedianFilerSizeEditField.Value),str2double(app.MedianFilerSizeEditField.Value)]);
                            bol=1;
                        else
                            uiwait(warndlg("Median Filter size value Must be Positive"));
                        end
                    end
                    if(bol==1)
                        %Show the fixed image
                        cla(app.RemoveNoiseGraph);
                        VisibiltyofGraph(app,app.RemoveNoiseGraph,"on","Salt and Pepper noise Removed by median Filter ");
                        imshow(medianfil,'parent',app.RemoveNoiseGraph);
                    end
                else
                     uiwait(warndlg("Apply Salt and Pepper Noise First"));
                end
            else
                if(app.pcheck==1)
                    % getting the fourier of the periodic noise to save it for
                    % later use
                    app.fourierperiodicnoise = fftshift(fft2(app.periodicnoise));
                    cla(app.FourierofNoise);
                    fl = log(1+abs(app.fourierperiodicnoise));
                    fm = max(fl(:));
                    VisibiltyofGraph(app,app.FourierofNoise,"on","Fourier of Periodic Noise Image ");
                    imshow(im2uint8(fl/fm),'parent',app.FourierofNoise);
                    %use the function Peaks Getter to calculate the peaks
                    %positions
                    PeaksGetter(app,app.fourierperiodicnoise);
                    val2=app.RemoveByDropDown.Value;
                    
                    % if BandReject Chosen a point from the peaks is chosen and
                    % then we make a matrix of distances that we use to make a
                    % range to find then we multiply it by the fourier of the
                    % periodic and then show the removed noise image
                    % CODE Mainly from tutorial but
                    if val2=="Band Reject"
                        x=app.rowsOfMaxes(1);
                        y=app.colsOfMaxes(1);
                        [s1,s2] = size(app.periodicnoise);
                        [cx,cy] = meshgrid(-s2/2 : s2/2-1 , -s1/2 : s1/2-1);
                        z = sqrt(cx.^2+cy.^2);
                        circle = z(x,y);
                        band_reject = (z < circle(1)-1 | z > circle(1)+1);
                        removedspikes = app.fourierperiodicnoise.*band_reject;
                        filteredImage = ifft2(ifftshift(removedspikes));
                        ampFilteredImage = abs(filteredImage);
                        ampFilteredImage = mat2gray(ampFilteredImage);
                        cla(app.RemoveNoiseGraph);
                        VisibiltyofGraph(app,app.RemoveNoiseGraph,"on","Periodic noise Removed by Band Reject Filter ");
                        imshow(ampFilteredImage,'parent',app.RemoveNoiseGraph);
                        
                        % Applying the Notch Filter by using the peaks calculated
                        % and then finding the rows and columns and setting them
                        % to zeros then show to filter image
                        % Code mainly from tutorial
                    elseif val2=="Notch Filter"
                        %Setting the pixels to zeros
                        app.fourierperiodicnoise(app.rowsOfMaxes(1),:) = 0;
                        app.fourierperiodicnoise(app.rowsOfMaxes(2),:) = 0;
                        app.fourierperiodicnoise(:,app.colsOfMaxes(1)) = 0;
                        app.fourierperiodicnoise(:,app.colsOfMaxes(2)) = 0;
                        % displaying the results of the filter
                        filteredImage = ifft2(ifftshift(app.fourierperiodicnoise));
                        ampFilteredImage = abs(filteredImage);
                        ampFilteredImage = mat2gray(ampFilteredImage);
                        cla(app.RemoveNoiseGraph);
                        VisibiltyofGraph(app,app.RemoveNoiseGraph,"on","Periodic noise Removed by Notch Filter ");
                        imshow(ampFilteredImage,'parent',app.RemoveNoiseGraph);
                    else
                        %Applying the Mask Filer byt getting the inputs from
                        %the user and then applying the mask by setting these
                        %two pixels by zero then showing the filtered image
                        fl = log(1+abs(app.fourierperiodicnoise));
                        fm = max(fl(:));
                        VisibiltyofGraph(app,app.FourierofNoise,"on","Select the Pixels ");                        
                        imshow(im2uint8(fl/fm),'parent',app.FourierofNoise);
                        
                        GetInputFromUser(app,app.FourierofNoise); %getting the input from the user function declared above
                        
                        freqImageNoisy = fftshift(fft2(app.periodicnoise));
                        magImageNoisy = log(abs(freqImageNoisy));
                        mask = ones(size(magImageNoisy));
                        mask(app.Pixel1(2),app.Pixel1(1))=0; %setting the max pixels to zero
                        mask(app.Pixel2(2),app.Pixel2(1))=0; %setting the max pixels to zero
                        filtered = mask.*freqImageNoisy;     % multiplying the mask to get filtered image
                        
                        % Displaying the results
                        filteredImage = ifft2(ifftshift(filtered));
                        ampFilteredImage = abs(filteredImage);
                        ampFilteredImage = mat2gray(ampFilteredImage);
                        cla(app.RemoveNoiseGraph);
                        VisibiltyofGraph(app,app.RemoveNoiseGraph,"on","Periodic noise Removed by Mask Filter ");
                        cla(app.FourierofNoise,'reset');
                        VisibiltyofGraph(app,app.FourierofNoise,"on","Fourier of Periodic Noise Image ");
                        fl = log(1+abs(app.fourierperiodicnoise));
                        fm = max(fl(:));
                        imshow(im2uint8(fl/fm),'parent',app.FourierofNoise);
                        imshow(ampFilteredImage,'parent',app.RemoveNoiseGraph);
                    end
                else
                    uiwait(warndlg("Apply Periodic Noise First"));
                    
                end
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.OriginalImage = uiaxes(app.HistogramTab);
            title(app.OriginalImage, 'No Image Selected Yet')
            app.OriginalImage.XTick = [];
            app.OriginalImage.XTickLabel = '';
            app.OriginalImage.YTick = [];
            app.OriginalImage.YTickLabel = {'['};
            app.BrowseButton = uibutton(app.HistogramTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.HistorEqualizationDropdown = uidropdown(app.HistogramTab);
            app.HistorEqualizationDropdown.Items = {'Histogram', 'Histogram Equalization'};
            app.HistorEqualizationDropdown.Visible = 'off';
            app.HistorEqualizationDropdown.Value = 'Histogram';
            app.ApplyButton = uibutton(app.HistogramTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Visible = 'off';
            app.ApplyButton.Text = 'Apply';
            app.GrayScale1 = uiaxes(app.HistogramTab);
            app.GrayScale1.XTick = [];
            app.GrayScale1.XTickLabel = '';
            app.GrayScale1.YTick = [];
            app.GrayScale1.YTickLabel = {'['};
            app.GrayScale1.Visible = 'off';
            app.EqualizedImage = uiaxes(app.HistogramTab);
            app.EqualizedImage.XTick = [];
            app.EqualizedImage.XTickLabel = {'['};
            app.EqualizedImage.YTick = [];
            app.EqualizedImage.YTickLabel = {'[ ]'};
            app.EqualizedImage.Visible = 'off';
            app.SelectwhichdoyouwanttoapplytoyourImageLabel = uilabel(app.HistogramTab);
            app.SelectwhichdoyouwanttoapplytoyourImageLabel.Visible = 'off';
            app.SelectwhichdoyouwanttoapplytoyourImageLabel.Text = 'Select which do you want to apply to your Image !';
            app.Data = uilabel(app.HistogramTab);
            app.Data.Visible = 'off';
            app.Data.Text = '';
            app.UploadYourImagetoStartLabel = uilabel(app.HistogramTab);
            app.UploadYourImagetoStartLabel.Text = 'Upload Your Image to Start';
            app.HistogramGraph = uiaxes(app.HistogramTab);
            title(app.HistogramGraph, 'Title')
            app.HistogramGraph.Visible = 'off';
            app.EqHistogramGraph = uiaxes(app.HistogramTab);
            title(app.EqHistogramGraph, 'Title')
            app.EqHistogramGraph.Visible = 'off';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.GrayScale2 = uiaxes(app.FilterTab);
            title(app.GrayScale2, 'No Image Selected Yet')
            app.GrayScale2.XTick = [];
            app.GrayScale2.XTickLabel = '';
            app.GrayScale2.YTick = [];
            app.GrayScale2.YTickLabel = {'['};
            app.ChooseFilterPanel = uipanel(app.FilterTab);
            app.ChooseFilterPanel.Title = 'Choose Filter';
            app.SobelorLaplaceDropDown = uidropdown(app.ChooseFilterPanel);
            app.SobelorLaplaceDropDown.Items = {'Sobel', 'Laplace'};
            app.SobelorLaplaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SobelorLaplaceDropDownValueChanged, true);
            app.SobelorLaplaceDropDown.Value = 'Sobel';
            app.ApplyFilterButton = uibutton(app.ChooseFilterPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.ThresholdEditFieldLabel = uilabel(app.ChooseFilterPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ChooseFilterPanel, 'text');
            app.ThresholdEditField.Value = 'Default';
            app.HorizontalorVertical = uidropdown(app.ChooseFilterPanel);
            app.HorizontalorVertical.Items = {'Horizontal', 'Vertical'};
            app.HorizontalorVertical.Value = 'Horizontal';
            app.FilterGraph = uiaxes(app.FilterTab);
            title(app.FilterGraph, 'No Image Selected Yet')
            app.FilterGraph.XTick = [];
            app.FilterGraph.XTickLabel = '';
            app.FilterGraph.YTick = [];
            app.FilterGraph.YTickLabel = {'['};
            app.FilterGraph.Visible = 'off';
            app.FourierTab = uitab(app.TabGroup);
            app.FourierTab.Title = 'Fourier ';
            app.GrayScale3 = uiaxes(app.FourierTab);
            title(app.GrayScale3, 'No Image Selected Yet')
            app.GrayScale3.XTick = [];
            app.GrayScale3.XTickLabel = '';
            app.GrayScale3.YTick = [];
            app.GrayScale3.YTickLabel = {'['};
            app.FourierGraph = uiaxes(app.FourierTab);
            title(app.FourierGraph, 'Fourier of Image')
            app.FourierGraph.XTick = [];
            app.FourierGraph.XTickLabel = '';
            app.FourierGraph.YTick = [];
            app.FourierGraph.YTickLabel = {'['};
            app.ApplyFourierButton = uibutton(app.FourierTab, 'push');
            app.ApplyFourierButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFourierButtonPushed, true);
            app.ApplyFourierButton.Text = 'Apply Fourier';
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.AddNoisePanel = uipanel(app.NoiseTab);
            app.AddNoisePanel.Title = 'Add Noise';
            app.ApplyNoiseButton = uibutton(app.AddNoisePanel, 'push');
            app.ApplyNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyNoiseButtonPushed, true);
            app.ApplyNoiseButton.Text = 'Apply Noise';
            app.AddNoiseDropDown = uidropdown(app.AddNoisePanel);
            app.AddNoiseDropDown.Items = {'Salt and Pepper ', 'Periodic Noise'};
            app.AddNoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @AddNoiseDropDownValueChanged, true);
            app.AddNoiseDropDown.Value = 'Salt and Pepper ';
            app.PercentageofNoisyPixelsLabel = uilabel(app.AddNoisePanel);
            app.PercentageofNoisyPixelsLabel.Text = 'Percentage of Noisy Pixels ';
            app.PercentageofNoisyPixelsEditField = uieditfield(app.AddNoisePanel, 'text');
            app.PercentageofNoisyPixelsEditField.Value = 'Default';
            app.SinorCosDropDownLabel = uilabel(app.AddNoisePanel);
            app.SinorCosDropDownLabel.Visible = 'off';
            app.SinorCosDropDownLabel.Text = 'Sin or Cos';
            app.SinorCosDropDown = uidropdown(app.AddNoisePanel);
            app.SinorCosDropDown.Items = {'Sin', 'Cos'};
            app.SinorCosDropDown.Visible = 'off';
            app.SinorCosDropDown.Value = 'Sin';
            app.nxEditFieldLabel = uilabel(app.AddNoisePanel);
            app.nxEditFieldLabel.Visible = 'off';
            app.nxEditFieldLabel.Text = 'nx';
            app.nxEditField = uieditfield(app.AddNoisePanel, 'text');
            app.nxEditField.Visible = 'off';
            app.nyEditFieldLabel = uilabel(app.AddNoisePanel);
            app.nyEditFieldLabel.Visible = 'off';
            app.nyEditFieldLabel.Text = 'ny';
            app.nyEditField = uieditfield(app.AddNoisePanel, 'text');
            app.nyEditField.Visible = 'off';
            app.RemoveNoisePanel = uipanel(app.NoiseTab);
            app.RemoveNoisePanel.Title = 'Remove Noise';
            app.RemoveNoiseButton = uibutton(app.RemoveNoisePanel, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.MedianFilerSizeEditFieldLabel = uilabel(app.RemoveNoisePanel);
            app.MedianFilerSizeEditFieldLabel.Text = 'Median Filer Size';
            app.MedianFilerSizeEditField = uieditfield(app.RemoveNoisePanel, 'text');
            app.MedianFilerSizeEditField.Value = 'Default';
            app.RemoveByDropDownLabel = uilabel(app.RemoveNoisePanel);
            app.RemoveByDropDownLabel.Visible = 'off';
            app.RemoveByDropDownLabel.Text = 'Remove By:';
            app.RemoveByDropDown = uidropdown(app.RemoveNoisePanel);
            app.RemoveByDropDown.Items = {'Band Reject', 'Notch Filter', 'Mask'};
            app.RemoveByDropDown.Visible = 'off';
            app.RemoveByDropDown.Value = 'Band Reject';
            app.NoisyImageGraph = uiaxes(app.NoiseTab);
            title(app.NoisyImageGraph, 'No Image Selected Yet')
            app.NoisyImageGraph.XTick = [];
            app.NoisyImageGraph.XTickLabel = '';
            app.NoisyImageGraph.YTick = [];
            app.NoisyImageGraph.YTickLabel = {'['};
            app.NoisyImageGraph.Visible = 'off';
            app.RemoveNoiseGraph = uiaxes(app.NoiseTab);
            title(app.RemoveNoiseGraph, 'No Image Selected Yet')
            app.RemoveNoiseGraph.XTick = [];
            app.RemoveNoiseGraph.XTickLabel = '';
            app.RemoveNoiseGraph.YTick = [];
            app.RemoveNoiseGraph.YTickLabel = {'['};
            app.RemoveNoiseGraph.Visible = 'off';
            app.GrayScale4 = uiaxes(app.NoiseTab);
            title(app.GrayScale4, 'No Image Selected Yet')
            app.GrayScale4.XTick = [];
            app.GrayScale4.XTickLabel = '';
            app.GrayScale4.YTick = [];
            app.GrayScale4.YTickLabel = {'['};
            app.FourierofNoise = uiaxes(app.NoiseTab);
            title(app.FourierofNoise, 'No Image Selected Yet')
            app.FourierofNoise.XTick = [];
            app.FourierofNoise.XTickLabel = '';
            app.FourierofNoise.YTick = [];
            app.FourierofNoise.YTickLabel = {'['};
            app.FourierofNoise.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisionProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end